classdef Do_An < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChonPhuongPhapListBox        matlab.ui.control.ListBox
        ChnPhngPhpLabel              matlab.ui.control.Label
        TinhvaVeButton               matlab.ui.control.Button
        SaiSoEditField               matlab.ui.control.EditField
        SaiSEditFieldLabel           matlab.ui.control.Label
        SoDoanConEditField           matlab.ui.control.EditField
        SonConEditFieldLabel         matlab.ui.control.Label
        GiaTriCuoiEditField          matlab.ui.control.EditField
        GiTrCuiEditFieldLabel        matlab.ui.control.Label
        GiaTriDauEditField           matlab.ui.control.EditField
        GiTruEditFieldLabel          matlab.ui.control.Label
        DieuKienDauEditField         matlab.ui.control.EditField
        iuKinuEditFieldLabel         matlab.ui.control.Label
        PhuongTrinhViPhanEditField   matlab.ui.control.EditField
        PhngTrnhViPhnEditFieldLabel  matlab.ui.control.Label
        DoThiUIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TinhvaVeButton
        function TinhvaVeButtonPushed(app, event)
            f = app.PhuongTrinhViPhanEditField.Value;
            fxy = str2func(['@(x,y)', f]);
            y0 = str2double(app.DieuKienDauEditField.Value);
            x0 = str2double(app.GiaTriDauEditField.Value);
            xn = str2double(app.GiaTriCuoiEditField.Value);
            N = str2double(app.SoDoanConEditField.Value);
            e = str2double(app.SaiSoEditField.Value);

            temp = app.ChonPhuongPhapListBox.Value;
            if (temp == "Ơ-le")
                [x,y] = Phuong_Phap_Ole(fxy, x0, xn, y0, N);
                plot(app.DoThiUIAxes, x, y, 'k*--');
            elseif (temp == "Hiện Ẩn Hình Thang")
                [x,y] = Hien_An_Hinh_Thang(fxy, x0, xn, y0, N, e);
                plot(app.DoThiUIAxes, x, y, 'ro--');
            elseif (temp == "Hiện Ẩn Trung Điểm")
                [x,y] = Hien_An_Trung_Diem(fxy, x0, xn, y0, N);
                plot(app.DoThiUIAxes, x, y, 'b+--');
            elseif (temp == "R-K")
                [x,y] = Phuong_Phap_RK(fxy, x0, xn, y0, N);
                plot(app.DoThiUIAxes, x, y, 'gv--');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoThiUIAxes = uiaxes(app.UIFigure);
            title(app.DoThiUIAxes, 'Đồ Thị')
            xlabel(app.DoThiUIAxes, 'X')
            ylabel(app.DoThiUIAxes, 'Y')
            zlabel(app.DoThiUIAxes, 'Z')
            app.PhngTrnhViPhnEditFieldLabel = uilabel(app.UIFigure);
            app.PhngTrnhViPhnEditFieldLabel.Text = 'Phương Trình Vi Phân';
            app.PhuongTrinhViPhanEditField = uieditfield(app.UIFigure, 'text');
            app.PhuongTrinhViPhanEditField.Value = '(1-x)*y';
            app.iuKinuEditFieldLabel = uilabel(app.UIFigure);
            app.iuKinuEditFieldLabel.Text = 'Điều Kiện Đầu';
            app.DieuKienDauEditField = uieditfield(app.UIFigure, 'text');
            app.DieuKienDauEditField.Value = '1';
            app.GiTruEditFieldLabel = uilabel(app.UIFigure);
            app.GiTruEditFieldLabel.Text = ' Giá Trị Đầu';
            app.GiaTriDauEditField = uieditfield(app.UIFigure, 'text');
            app.GiaTriDauEditField.Value = '0';
            app.GiTrCuiEditFieldLabel = uilabel(app.UIFigure);
            app.GiTrCuiEditFieldLabel.Text = 'Giá Trị Cuối ';
            app.GiaTriCuoiEditField = uieditfield(app.UIFigure, 'text');
            app.GiaTriCuoiEditField.Value = '5';
            app.SonConEditFieldLabel = uilabel(app.UIFigure);
            app.SonConEditFieldLabel.Text = 'Số Đoạn Con ';
            app.SoDoanConEditField = uieditfield(app.UIFigure, 'text');
            app.SoDoanConEditField.Value = '25';
            app.SaiSEditFieldLabel = uilabel(app.UIFigure);
            app.SaiSEditFieldLabel.Text = 'Sai Số';
            app.SaiSoEditField = uieditfield(app.UIFigure, 'text');
            app.SaiSoEditField.Value = '0.001';
            app.TinhvaVeButton = uibutton(app.UIFigure, 'push');
            app.TinhvaVeButton.ButtonPushedFcn = createCallbackFcn(app, @TinhvaVeButtonPushed, true);
            app.TinhvaVeButton.Text = 'Tính và Vẽ';
            app.ChnPhngPhpLabel = uilabel(app.UIFigure);
            app.ChnPhngPhpLabel.Text = 'Chọn Phương Pháp';
            app.ChonPhuongPhapListBox = uilistbox(app.UIFigure);
            app.ChonPhuongPhapListBox.Items = {'Ơ-le', 'Hiện Ẩn Hình Thang', 'Hiện Ẩn Trung Điểm', 'R-K'};
            app.ChonPhuongPhapListBox.Value = 'Ơ-le';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Do_An

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end